classdef son < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SolResimTab                    matlab.ui.container.Tab
        SeButton                       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        VeriGiriYntemiDropDownLabel    matlab.ui.control.Label
        VeriGiriYntemiDropDown         matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        Button2                        matlab.ui.control.Button
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        xoEditFieldLabel               matlab.ui.control.Label
        xoEditField                    matlab.ui.control.NumericEditField
        yoEditFieldLabel               matlab.ui.control.Label
        yoEditField                    matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        HEditFieldLabel                matlab.ui.control.Label
        HEditField                     matlab.ui.control.NumericEditField
        hEditFieldLabel                matlab.ui.control.Label
        hEditField                     matlab.ui.control.NumericEditField
        PxEditFieldLabel               matlab.ui.control.Label
        PxEditField                    matlab.ui.control.NumericEditField
        cEditFieldLabel                matlab.ui.control.Label
        cEditField                     matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        EditField_5                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EditField_4                    matlab.ui.control.NumericEditField
        KaydetButton_2                 matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        ExcelButton                    matlab.ui.control.Button
        SaResimTab                     matlab.ui.container.Tab
        SeButton_2                     matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        ExcelButton_2                  matlab.ui.control.Button
        VeriGiriYntemiDropDown_2Label  matlab.ui.control.Label
        VeriGiriYntemiDropDown_2       matlab.ui.control.DropDown
        UIAxes_2                       matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        XEditField_2Label              matlab.ui.control.Label
        XEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2Label              matlab.ui.control.Label
        YEditField_2                   matlab.ui.control.NumericEditField
        ZEditField_2Label              matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        EditField_6Label               matlab.ui.control.Label
        EditField_6                    matlab.ui.control.NumericEditField
        EditField_7Label               matlab.ui.control.Label
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_8Label               matlab.ui.control.Label
        EditField_8                    matlab.ui.control.NumericEditField
        KaydetButton                   matlab.ui.control.Button
        ProcessTab                     matlab.ui.container.Tab
        AraziKoordinatlarPanel         matlab.ui.container.Panel
        KaydetButton_3                 matlab.ui.control.Button
        HesaplaButton                  matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        KaydetButton_5                 matlab.ui.control.Button
        ExcelLabel                     matlab.ui.control.Label
        NetcadLabel                    matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        ResimKoordinatlarPanel         matlab.ui.container.Panel
        HesaplaButton_2                matlab.ui.control.Button
        KaydetButton_4                 matlab.ui.control.Button
        BrowseButton_2                 matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        NoktaiftlerininSeilmesiPanel   matlab.ui.container.Panel
        NoktaSeimiButton               matlab.ui.control.Button
    end


    properties (Access = private)
        R1
        R2
        a3
        G
        m1
        m2
        n1
        n2
        YY
        TT
        nn
        xrl
        xrr
        yrl
        yrr
        X0
        Y0
        Z0
        xo
        yo
        px
        J1
        om1
        c
        kx
        ky
        arazi
        dosya1
        adres1
        dosya
        adres
    end

    properties (Access = public)
        a1
        a2
        Rkl
        Rkk
    end

    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: SeButton
        function SeButtonPushed(app, event)
            [resim,adres] = uigetfile('.tif','resim');
            Ri=strcat(adres,resim);
            R{1}=imread(Ri);
            image(R{1},'Parent',app.UIAxes);    
            set(app.UIAxes,'visible','off');    
            app.R1=R{1};
        end

        % Value changed function: VeriGiriYntemiDropDown
        function VeriGiriYntemiDropDownValueChanged(app, event)
            value = app.VeriGiriYntemiDropDown.Value;
            if strcmp(value,'Otomatik')
                app.Panel.Visible = 'off';
                app.Panel_2.Visible = 'on';
            elseif strcmp(value,'Manuel')
                app.Panel.Visible = 'on';
                app.Panel_2.Visible = 'off';
            end
            
        end

        % Button pushed function: SeButton_2
        function SeButton_2Pushed(app, event)
            [resim,adres] = uigetfile('.tif','resim');
            Ri=strcat(adres,resim);
            R{2}=imread(Ri);
            image(R{2},'Parent',app.UIAxes_2);    
            set(app.UIAxes_2,'visible','off');   
            app.R2=R{2};
        end

        % Value changed function: VeriGiriYntemiDropDown_2
        function VeriGiriYntemiDropDown_2ValueChanged(app, event)
            value = app.VeriGiriYntemiDropDown_2.Value;
            if strcmp(value,'Otomatik')
                app.Panel_3.Visible = 'off';
                app.Panel_4.Visible = 'on';
            elseif strcmp(value, 'Manuel')
                app.Panel_3.Visible = 'on';
                app.Panel_4.Visible = 'off';
            end
            
        end

        % Button pushed function: ExcelButton
        function ExcelButtonPushed(app, event)
            [excel,adres] = uigetfile('.xlsx','dosya');
            Ri=strcat(adres,excel);
            G=xlsread(Ri);
            app.G=G;
        end

        % Button pushed function: ExcelButton_2
        function ExcelButton_2Pushed(app, event)
            [excel,adres] = uigetfile('.xlsx','dosya');
            Ri=strcat(adres,excel);
            G=xlsread(Ri);
            App.G=G;
        end

        % Button pushed function: KaydetButton_2
        function KaydetButton_2Pushed(app, event)
            G=zeros(2,12);
            G(1,1)=app.XEditField.Value;
            G(1,2)=app.YEditField.Value;
            G(1,3)=app.ZEditField.Value;
            G(1,4)=app.EditField_3.Value;
            G(1,5)=app.EditField_5.Value;
            G(1,6)=app.EditField_4.Value;
            G(1,7)=app.xoEditField.Value;
            G(2,7)=app.yoEditField.Value;
            G(1,8)=app.HEditField.Value;
            G(1,9)=app.hEditField.Value;
            G(1,10)=app.PxEditField.Value;
            G(1,11)=app.cEditField.Value;
            app.G=G;
        end

        % Button pushed function: KaydetButton
        function KaydetButtonPushed(app, event)
            G=app.G;
            G(2,1)=app.XEditField_2.Value;
            G(2,2)=app.YEditField_2.Value;
            G(2,3)=app.ZEditField_2.Value;
            G(2,4)=app.EditField_6.Value;
            G(2,5)=app.EditField_7.Value;
            G(2,6)=app.EditField_8.Value;
            app.G=G;
        end

        % Button pushed function: NoktaSeimiButton
        function NoktaSeimiButtonPushed(app, event)
            R{1}=app.R1;
            R{2}=app.R2;
            [m1 n1]=size(R{1}(:,:,1));
            [m2 n2]=size(R{2}(:,:,1));
            [a1 a2]=cpselect(R{1},R{2},'Wait',true);
            [m3 n3]=size(a1);
            [a3]=[a1,a2];
            app.m1=m1;
            app.m2=m2;
            app.n1=n1;
            app.n2=n2
            app.a1=a1;
            app.a2=a2;
            app.a3=a3;
        end

        % Button pushed function: HesaplaButton_2
        function HesaplaButton_2Pushed(app, event)
            a3=app.a3;
            n1=app.n1;
            a1=app.a1;
            m1=app.m1;
            n2=app.n2;
            a2=app.a2;
            m2=app.m2;
            G=app.G;
            xo=G(1,7);
            yo=G(2,7);
            px=G(1,10);
            ky=a3(:,3:4);
            kx=a3(:,1:2);
            [m n]=size(kx);
            nn=(1:m);
            xrl=-1*(n1/2-a1(:,1))*(px/1000)-xo;
            yrl=-1*(a1(:,2)-m1/2)*(px/1000)-yo;
            xrr=-1*(n2/2-a2(:,1))*(px/1000)-xo;
            yrr=-1*(a2(:,2)-m2/2)*(px/1000)-yo;
            YY(1,:)=xrl;
            YY(2,:)=yrl;
            TT(1,:)=xrr;
            TT(2,:)=yrr;
            app.YY=YY;
            app.TT=TT;
            app.nn=nn;
            app.xrl=xrl;
            app.yrl=yrl;
            app.xrr=xrr;
            app.yrr=yrr;
            app.kx=kx;
            app.ky=ky;
            app.xo=xo;
            app.yo=yo;
            
        end

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
            G=app.G;
            kx=app.kx;
            X0=G(:,1);
            Y0=G(:,2);
            Z0=G(:,3);
            c=G(1,11);
            X=(X0(1,1)+X0(2,1))/2;
            Y=(Y0(1,1)+Y0(2,1))/2;
            xrl=app.xrl;
            xrr=app.xrr;
            yrl=app.yrl;
            yrr=app.yrr;
            xo=app.xo;
            yo=app.yo;
            degrad=180/pi;
            om1=G(1,4)/degrad;om2=G(2,4)/degrad;
            ph1=G(1,5)/degrad;ph2=G(2,5)/degrad;
            kp1=G(1,6)/degrad;kp2=G(2,6)/degrad;
            for k=1:length(kx);
                J1=zeros(14,1); p2=zeros(14,1);
                dp=ones(14,1)*1.0e-08;
                J1(1)=xrl(k,1);  
                J1(2)=yrl(k,1);  
                J1(3)=xo; 
                J1(4)=yo;  
                J1(5)=c;
                J1(6)=om1;
                J1(7)=ph1; 
                J1(8)=kp1; 
                J1(9)=X0(1,1); 
                J1(10)=Y0(1,1); 
                J1(11)=Z0(1,1);
                J1(12)=X;   J1(13)=Y;  J1(14)=G(1,8);
                p2(1)=xrr(k,1);  p2(2)=yrr(k,1);  p2(3)=xo;  p2(4)=yo;  p2(5)=c;  p2(6)=om2;
                p2(7)=ph2;  p2(8)=kp2;  p2(9)=X0(2,1);  p2(10)=Y0(2,1);   p2(11)=Z0(2,1);
                p2(12)=X;   p2(13)=Y;  p2(14)=G(1,8);  
                partials=zeros(2,14);
                for iter=1:5
                    B=zeros(4,3);
                    f=zeros(4,1);
                    m1=[1 0 0;0 cos(J1(6)) sin(J1(6));0 -sin(J1(6)) cos(J1(6))];
                    m2=[cos(J1(7)) 0 -sin(J1(7));0 1 0;sin(J1(7)) 0 cos(J1(7))];
                    m3=[cos(J1(8)) sin(J1(8)) 0;-sin(J1(8)) cos(J1(8)) 0;0 0 1];
                    M=m3*m2*m1;
                    UVW=M*[J1(12)-J1(9);J1(13)-J1(10);J1(14)-J1(11)];
                    U=UVW(1);
                    V=UVW(2);
                    W=UVW(3);
                    Fx=J1(1)-J1(3)+J1(5)*(U/W);
                    Fy=J1(2)-J1(4)+J1(5)*(V/W);
                    F0=[Fx;Fy];
                    for j=1:14
                        pp=J1;
                        pp(j)=pp(j)+dp(j);
                        m1=[1 0 0;0 cos(pp(6)) sin(pp(6));0 -sin(pp(6)) cos(pp(6))];
                        m2=[cos(pp(7)) 0 -sin(pp(7));0 1 0;sin(pp(7)) 0 cos(pp(7))];
                        m3=[cos(pp(8)) sin(pp(8)) 0;-sin(pp(8)) cos(pp(8)) 0;0 0 1];
                        M=m3*m2*m1;
                        UVW=M*[pp(12)-pp(9);pp(13)-pp(10);pp(14)-pp(11)];
                        U=UVW(1);
                        V=UVW(2);
                        W=UVW(3);
                        Fx=pp(1)-pp(3)+pp(5)*(U/W);
                        Fy=pp(2)-pp(4)+pp(5)*(V/W);
                        F1=[Fx;Fy]
                        partials(:,j)=(F1-F0)*(1/dp(j));
                    end
                    B(1:2,:)=partials(:,12:14);
                    f(1:2)=-F0;
                    m1=[1 0 0;0 cos(p2(6)) sin(p2(6));0 -sin(p2(6)) cos(p2(6))];
                    m2=[cos(p2(7)) 0 -sin(p2(7));0 1 0;sin(p2(7)) 0 cos(p2(7))];
                    m3=[cos(p2(8)) sin(p2(8)) 0;-sin(p2(8)) cos(p2(8)) 0;0 0 1];
                    M=m3*m2*m1;
                    UVW=M*[p2(12)-p2(9);p2(13)-p2(10);p2(14)-p2(11)];
                    U=UVW(1);
                    V=UVW(2);
                    W=UVW(3);
                    Fx=p2(1)-p2(3)+p2(5)*(U/W);
                    Fy=p2(2)-p2(4)+p2(5)*(V/W);
                    F0=[Fx;Fy];
                    for j=1:14
                        pp=p2;
                        pp(j)=pp(j) + dp(j);
                        m1=[1 0 0;0 cos(pp(6)) sin(pp(6));0 -sin(pp(6)) cos(pp(6))];
                        m2=[cos(pp(7)) 0 -sin(pp(7));0 1 0;sin(pp(7)) 0 cos(pp(7))];
                        m3=[cos(pp(8)) sin(pp(8)) 0;-sin(pp(8)) cos(pp(8)) 0;0 0 1];
                        M=m3*m2*m1;
                        UVW=M*[pp(12)-pp(9);pp(13)-pp(10);pp(14)-pp(11)];
                        U=UVW(1);
                        V=UVW(2);
                        W=UVW(3);
                        Fx=pp(1)-pp(3)+pp(5)*(U/W);
                        Fy=pp(2)-pp(4)+pp(5)*(V/W);
                        F1=[Fx;Fy];
                        partials(:,j)=(F1-F0)*(1/dp(j));
                    end
                    B(3:4,:)=partials(:,12:14);
                    f(3:4)=-F0;
                    del=inv(B'*B)*B'*f;
                    del';
                    X=X+del(1);
                    Y=Y+del(2);
                    Z=G(1,8)+del(3);
                    J1(12:14)=[X;Y;Z];
                    p2(12:14)=[X;Y;Z];
                end
                NN=1:length(kx);
                arazi(:,1)=NN;
                arazi(k,2:4)=[X Y Z];
                v=f-B*del;
            end
            app.arazi=arazi;
        end

        % Button pushed function: KaydetButton_4
        function KaydetButton_4Pushed(app, event)
            adres=app.adres;
            YY=app.YY;
            TT=app.TT;
            xlswrite('Sol_Resim.xls',YY);
            xlswrite('Sağ_Resim.xls',TT);
            movefile('Sol_Resim.xls',adres);
            movefile('Sağ_Resim.xls',adres);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [dosya,adres] = uiputfile('.xls','resim koordinatları');
            app.dosya=dosya;
            app.adres=adres;
            app.TextArea.Value= strcat(adres,dosya);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [dosya1,adres1] = uiputfile('.xls','resim');
            app.dosya1=dosya1;
            app.adres1=adres1;
            app.TextArea_2.Value= strcat(adres1,dosya1);
        end

        % Button pushed function: KaydetButton_3
        function KaydetButton_3Pushed(app, event)
            arazi=app.arazi;
            adres1=app.adres1;
            dosya1=app.dosya1;
            xlswrite(dosya1,arazi);
            movefile(dosya1,adres1);
        end

        % Button pushed function: KaydetButton_5
        function KaydetButton_5Pushed(app, event)
            dosya1=app.dosya1;
            adres1=app.adres1;
            arazi=app.arazi;
            save('cagatay.ncn','arazi','-ascii');
            movefile('cagatay.ncn',adres1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SolResimTab = uitab(app.TabGroup);
            app.SolResimTab.Title = 'Sol Resim';
            app.SeButton = uibutton(app.SolResimTab, 'push');
            app.SeButton.ButtonPushedFcn = createCallbackFcn(app, @SeButtonPushed, true);
            app.SeButton.Text = 'Seç';
            app.UIAxes = uiaxes(app.SolResimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.VeriGiriYntemiDropDownLabel = uilabel(app.SolResimTab);
            app.VeriGiriYntemiDropDownLabel.Text = 'Veri Giriş Yöntemi';
            app.VeriGiriYntemiDropDown = uidropdown(app.SolResimTab);
            app.VeriGiriYntemiDropDown.Items = {'Otomatik', 'Manuel'};
            app.VeriGiriYntemiDropDown.ValueChangedFcn = createCallbackFcn(app, @VeriGiriYntemiDropDownValueChanged, true);
            app.VeriGiriYntemiDropDown.Value = 'Otomatik';
            app.Panel = uipanel(app.SolResimTab);
            app.Button2 = uibutton(app.Panel, 'push');
            app.Button2.Text = 'Button2';
            app.XEditFieldLabel = uilabel(app.Panel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.Panel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel, 'numeric');
            app.xoEditFieldLabel = uilabel(app.Panel);
            app.xoEditFieldLabel.Text = 'xo';
            app.xoEditField = uieditfield(app.Panel, 'numeric');
            app.yoEditFieldLabel = uilabel(app.Panel);
            app.yoEditFieldLabel.Text = 'yo';
            app.yoEditField = uieditfield(app.Panel, 'numeric');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'ω';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.HEditFieldLabel = uilabel(app.Panel);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.Panel, 'numeric');
            app.hEditFieldLabel = uilabel(app.Panel);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.Panel, 'numeric');
            app.PxEditFieldLabel = uilabel(app.Panel);
            app.PxEditFieldLabel.Text = 'Px';
            app.PxEditField = uieditfield(app.Panel, 'numeric');
            app.cEditFieldLabel = uilabel(app.Panel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Panel, 'numeric');
            app.EditField_5Label = uilabel(app.Panel);
            app.EditField_5Label.Text = 'φ';
            app.EditField_5 = uieditfield(app.Panel, 'numeric');
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'χ';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.KaydetButton_2 = uibutton(app.Panel, 'push');
            app.KaydetButton_2.ButtonPushedFcn = createCallbackFcn(app, @KaydetButton_2Pushed, true);
            app.KaydetButton_2.Text = 'Kaydet';
            app.Panel_2 = uipanel(app.SolResimTab);
            app.ExcelButton = uibutton(app.Panel_2, 'push');
            app.ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
            app.ExcelButton.Text = 'Excel';
            app.SaResimTab = uitab(app.TabGroup);
            app.SaResimTab.Title = 'Sağ Resim';
            app.SeButton_2 = uibutton(app.SaResimTab, 'push');
            app.SeButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeButton_2Pushed, true);
            app.SeButton_2.Text = 'Seç';
            app.Panel_4 = uipanel(app.SaResimTab);
            app.ExcelButton_2 = uibutton(app.Panel_4, 'push');
            app.ExcelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExcelButton_2Pushed, true);
            app.ExcelButton_2.Text = 'Excel';
            app.VeriGiriYntemiDropDown_2Label = uilabel(app.SaResimTab);
            app.VeriGiriYntemiDropDown_2Label.Text = 'Veri Giriş Yöntemi';
            app.VeriGiriYntemiDropDown_2 = uidropdown(app.SaResimTab);
            app.VeriGiriYntemiDropDown_2.Items = {'Otomatik', 'Manuel'};
            app.VeriGiriYntemiDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VeriGiriYntemiDropDown_2ValueChanged, true);
            app.VeriGiriYntemiDropDown_2.Value = 'Manuel';
            app.UIAxes_2 = uiaxes(app.SaResimTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Panel_3 = uipanel(app.SaResimTab);
            app.XEditField_2Label = uilabel(app.Panel_3);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.YEditField_2Label = uilabel(app.Panel_3);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.ZEditField_2Label = uilabel(app.Panel_3);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_6Label = uilabel(app.Panel_3);
            app.EditField_6Label.Text = 'ω';
            app.EditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_7Label = uilabel(app.Panel_3);
            app.EditField_7Label.Text = 'φ';
            app.EditField_7 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_8Label = uilabel(app.Panel_3);
            app.EditField_8Label.Text = 'χ';
            app.EditField_8 = uieditfield(app.Panel_3, 'numeric');
            app.KaydetButton = uibutton(app.Panel_3, 'push');
            app.KaydetButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetButtonPushed, true);
            app.KaydetButton.Text = 'Kaydet';
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.AraziKoordinatlarPanel = uipanel(app.ProcessTab);
            app.AraziKoordinatlarPanel.Title = 'Arazi Koordinatları';
            app.KaydetButton_3 = uibutton(app.AraziKoordinatlarPanel, 'push');
            app.KaydetButton_3.ButtonPushedFcn = createCallbackFcn(app, @KaydetButton_3Pushed, true);
            app.KaydetButton_3.Text = 'Kaydet';
            app.HesaplaButton = uibutton(app.AraziKoordinatlarPanel, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.BrowseButton = uibutton(app.AraziKoordinatlarPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.KaydetButton_5 = uibutton(app.AraziKoordinatlarPanel, 'push');
            app.KaydetButton_5.ButtonPushedFcn = createCallbackFcn(app, @KaydetButton_5Pushed, true);
            app.KaydetButton_5.Text = 'Kaydet';
            app.ExcelLabel = uilabel(app.AraziKoordinatlarPanel);
            app.ExcelLabel.Text = 'Excel';
            app.NetcadLabel = uilabel(app.AraziKoordinatlarPanel);
            app.NetcadLabel.Text = 'Netcad';
            app.TextArea_2 = uitextarea(app.AraziKoordinatlarPanel);
            app.ResimKoordinatlarPanel = uipanel(app.ProcessTab);
            app.ResimKoordinatlarPanel.Title = 'Resim Koordinatları';
            app.HesaplaButton_2 = uibutton(app.ResimKoordinatlarPanel, 'push');
            app.HesaplaButton_2.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButton_2Pushed, true);
            app.HesaplaButton_2.Text = 'Hesapla';
            app.KaydetButton_4 = uibutton(app.ResimKoordinatlarPanel, 'push');
            app.KaydetButton_4.ButtonPushedFcn = createCallbackFcn(app, @KaydetButton_4Pushed, true);
            app.KaydetButton_4.Text = 'Kaydet';
            app.BrowseButton_2 = uibutton(app.ResimKoordinatlarPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.TextArea = uitextarea(app.ResimKoordinatlarPanel);
            app.NoktaiftlerininSeilmesiPanel = uipanel(app.ProcessTab);
            app.NoktaiftlerininSeilmesiPanel.Title = 'Nokta Çiftlerinin Seçilmesi';
            app.NoktaSeimiButton = uibutton(app.NoktaiftlerininSeilmesiPanel, 'push');
            app.NoktaSeimiButton.ButtonPushedFcn = createCallbackFcn(app, @NoktaSeimiButtonPushed, true);
            app.NoktaSeimiButton.Text = 'Nokta Seçimi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = son

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end